classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CharacterRecognizerUIFigure  matlab.ui.Figure
        IdentifyLetterButton         matlab.ui.control.Button
        CharacterRecognizerLabel     matlab.ui.control.Label
        OutputTextAreaLabel          matlab.ui.control.Label
        OutputTextArea               matlab.ui.control.TextArea
        EnterInputButton             matlab.ui.control.Button
        InputmustbeanuppercaseLabel  matlab.ui.control.Label
        characterfromAtoZLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        netClassifier % Classifier neural network
        inputDrawer %input drawer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load net.mat net;
            app.netClassifier = net;
            app.inputDrawer = drawInput();
        end

        % Button pushed function: EnterInputButton
        function EnterInputButtonPushed(app, event)
            app.inputDrawer.draw();
        end

        % Button pushed function: IdentifyLetterButton
        function IdentifyLetterButtonPushed(app, event)
            letter = app.netClassifier.classify(app.inputDrawer.inputPicture);
            %disp(letter);
            app.OutputTextArea.Value = cellstr(letter);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CharacterRecognizerUIFigure = uifigure('Visible', 'off');
            app.CharacterRecognizerUIFigure.Name = 'Character Recognizer';
            app.IdentifyLetterButton = uibutton(app.CharacterRecognizerUIFigure, 'push');
            app.IdentifyLetterButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyLetterButtonPushed, true);
            app.IdentifyLetterButton.Text = 'Identify Letter';
            app.CharacterRecognizerLabel = uilabel(app.CharacterRecognizerUIFigure);
            app.CharacterRecognizerLabel.Text = 'Character Recognizer';
            app.OutputTextAreaLabel = uilabel(app.CharacterRecognizerUIFigure);
            app.OutputTextAreaLabel.Text = 'Output:';
            app.OutputTextArea = uitextarea(app.CharacterRecognizerUIFigure);
            app.OutputTextArea.Editable = 'off';
            app.EnterInputButton = uibutton(app.CharacterRecognizerUIFigure, 'push');
            app.EnterInputButton.ButtonPushedFcn = createCallbackFcn(app, @EnterInputButtonPushed, true);
            app.EnterInputButton.Text = 'Enter Input';
            app.InputmustbeanuppercaseLabel = uilabel(app.CharacterRecognizerUIFigure);
            app.InputmustbeanuppercaseLabel.Text = '(Input must be an upper case ';
            app.characterfromAtoZLabel = uilabel(app.CharacterRecognizerUIFigure);
            app.characterfromAtoZLabel.Text = 'character from A to Z)';
            % Show the figure after all components are created
            app.CharacterRecognizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CharacterRecognizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CharacterRecognizerUIFigure)
        end
    end
end